classdef PDS_TF_EXP2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        EXP1Tab                   matlab.ui.container.Tab
        GrabarExcelButton         matlab.ui.control.Button
        AnalizarButton            matlab.ui.control.Button
        AnalisisECGTextArea       matlab.ui.control.TextArea
        AnalisisECGTextAreaLabel  matlab.ui.control.Label
        NEditField                matlab.ui.control.EditField
        NEditFieldLabel           matlab.ui.control.Label
        ArchivosECGDropDown       matlab.ui.control.DropDown
        ArchivosECGDropDownLabel  matlab.ui.control.Label
        FilteredIIRButtonGroup    matlab.ui.container.ButtonGroup
        SpectrogramButton_5       matlab.ui.control.RadioButton
        FaseButton_4              matlab.ui.control.RadioButton
        MagSpectButton_7          matlab.ui.control.RadioButton
        GenerarfiltradoButton     matlab.ui.control.Button
        FilteredFIRButtonGroup    matlab.ui.container.ButtonGroup
        TiempoButton_2            matlab.ui.control.RadioButton
        SpectrogramButton_4       matlab.ui.control.RadioButton
        FaseButton_3              matlab.ui.control.RadioButton
        MagSpectButton_6          matlab.ui.control.RadioButton
        ParametrosFIRLabel        matlab.ui.control.Label
        IIRButtonGroup            matlab.ui.container.ButtonGroup
        FaseButton_2              matlab.ui.control.RadioButton
        MagSpectButton_5          matlab.ui.control.RadioButton
        TantoporunoButton_2       matlab.ui.control.RadioButton
        ImpulsoButton_2           matlab.ui.control.RadioButton
        FIRButtonGroup            matlab.ui.container.ButtonGroup
        FaseButton                matlab.ui.control.RadioButton
        MagSpectButton_4          matlab.ui.control.RadioButton
        TantoporunoButton         matlab.ui.control.RadioButton
        ImpulsoButton             matlab.ui.control.RadioButton
        f_supEditField            matlab.ui.control.EditField
        f_supEditFieldLabel       matlab.ui.control.Label
        f_infEditField            matlab.ui.control.EditField
        f_infEditFieldLabel       matlab.ui.control.Label
        ECGButtonGroup            matlab.ui.container.ButtonGroup
        SpectrogramButton         matlab.ui.control.RadioButton
        MagSpectButton            matlab.ui.control.RadioButton
        TiempoButton              matlab.ui.control.RadioButton
        UIAxes8                   matlab.ui.control.UIAxes
        UIAxes7                   matlab.ui.control.UIAxes
        UIAxes6                   matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        inputdict
        outputdict
        diagnosis
        report
        P
        Q
        R
        S
        fs = 500; %Frecuencia de muestreo
        r = 24; %Resolucion
        x1 = []; % Frase
        x2 = []; % Silbido
        x = []; % Combinado
        y_FIR = [] % Señal filtrada con FIR
        y_IIR = []; % Señal filtrada con IIR
        h = [];
        H = [];
        FREC = [];
        Hf = [];
        impulse = [];
        hab = [];
        HA = [];
        HAf = [];
    end
    
    methods (Access = public)
        
        function toggle_button(app, varargin)
            for i=1:length(varargin)
                button = varargin{i};
                state = get(button, 'Enable');
                set(button, 'Enable', ~state);
            end
        end
        
        function toggle_button_on(app, varargin)
            for i=1:length(varargin)
                button = varargin{i};
                set(button, 'Enable', 'on');
            end
        end
        
        function toggle_button_off(app, varargin)
            for i=1:length(varargin)
                button = varargin{i};
                set(button, 'Enable', 'off');
            end
        end
        
        function format_graphs(app, xlim, xticks, xlabel, ylim, yticks, ylabel, varargin)
            %xlim, xticks, ylim and yticks must be arrays containing arrays
            %xlabel, ylabel son un string array

            if length(varargin) == 1
                %Eje X
                disp('hola')
                disp(varargin{1})
                varargin{1}.XGrid = "on";
                varargin{1}.XLim = xlim;
                varargin{1}.XTick = xticks;
                varargin{1}.XLabel.String = xlabel;

                %Eje Y
                varargin{1}.YGrid = "on";
                varargin{1}.YLim = ylim;
                varargin{1}.YTick = yticks;
                varargin{1}.YLabel.String = ylabel;
                return
            end

            for i=1:length(varargin)
                %Eje X
                varargin{i}.XGrid = "on";
                varargin{i}.XLim = xlim(i, :);
                varargin{i}.XTick = xticks(i, :);
                varargin{i}.XLabel.String = xlabel(i);

                %Eje Y
                varargin{i}.YGrid = "on";
                varargin{i}.YLim = ylim(i, :);
                varargin{i}.YTick = yticks(i, :);
                varargin{i}.YLabel.String = ylabel(i);
            end
        end
        
        function results = spectrogramgraph(app, graph, data)
            Fs = app.fs;
            app.clear_graph(graph)
            tiempo_ventana = 30*1e-3;
            tiempo_traslape = 0;
            tam_ventana = Fs*tiempo_ventana;
            tam_traslape = Fs*tiempo_traslape;
            nfft = 2^nextpow2(tam_ventana);
            [S, F, T] = spectrogram(data, tam_ventana, tam_traslape, nfft, Fs, 'yaxis');

            Tc = width(data(1:end-1))/Fs;
            xaxis = data;
            xlim = [0 Tc];
            xticks = 0:0.5:Tc;
            xtitle = 'Tiempo (s)';
            yaxis = F;
            ylim = [0 max(F)];
            yticks = 0:1000:max(F);
            ytitle = 'Frecuencia (Hz)';
            graph.XScale = 'linear';
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)

            spectrogram(data, tam_ventana, tam_traslape, nfft, Fs, 'yaxis');
            imagesc(graph, T, F, log(1+abs(S)));
            set(graph, 'YDir', 'normal')
            axis square
            axis tight
            colorbar
        end
        
        function results = magspectgraph(app, graph, data)
            Fs = app.fs;
            app.clear_graph(graph)
            raw = data;
            Nx = length(data);
            X = fft(raw);                 % Transformación de Fourier
            X = X(1:Nx/2+1);            % Frecuencias positivas
            fx = (0:Nx/2)' * Fs/Nx;     % Dominio de frecuencia de la señal de audio

            xaxis = fx;
            % xlim = [.1 max(xaxis)];
            % xticks = 0:1000:max(xaxis);
            % xtitle = 'Frecuencia (Hz)';
            yaxis = abs(X);
            % ylim = [min(yaxis) max(yaxis)];
            % yticks = min(yaxis):max(yaxis);
            % ytitle = 'Amplitud';
            % app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)

            graph.XLim = [.1 0.25];
            graph.XTick = 2.^(-2:-1);
            graph.XScale = 'log';
            graph.XTickLabel = 2.^(-2:-1);
            graph.YLim = [-17 56];
            graph.YTick = -20:20:60;
            graph.XLabel.String = 'f';
            graph.YLabel.String = 'Amplitud';
            grid on;
            plot(graph, xaxis*1e-3, 20*log10(yaxis));
            grid on;
        end
        
        function results = timegraph(app, graph, data)
            app.clear_graph(graph)
            Fs = app.fs;
            Tc = width(data(1:end-1))/Fs;
            xaxis = 1:1:2000; 
            xlim = [0 2000];
            xticks = 0:200:2000;
            xtitle = 'Tiempo (s)';
            yaxis = data;
            ylim = [-2 2];
            yticks = -2:0.5:2;
            ytitle = 'Amplitud';

            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            graph.XScale = 'linear';
            graph.XTick = xticks;
            graph.XLim = xlim;
            grid on;
            plot(graph, xaxis, yaxis)
        end
        
        function clear_graph(app, varargin)
            cellfun(@cla, varargin)
        end
        
        function results = radio_graph_select(app, radio, graph, data)
            graphs = ["Tiempo", "MagSpect", "Spectrogram", "Fase"];
            choice = radio.SelectedObject.Text;
            [~ , idx] = ismember(choice, graphs);
            if idx == 1
                app.timegraph(graph, data)
            elseif idx == 2
                app.magspectgraph(graph, data)
            elseif idx == 3
                app.spectrogramgraph(graph, data)
            else
                app.fasegraph(graph, data)
            end
        end
        
        function [h, H, FREC, Hf] = FIR_rechazabanda(app, f_inf, f_sup)
            % Indicar de la frecuencia del tono f_inf, f_sup y bw
            %bw = f_sup - f_inf ;

            % Conversion a frecuencia normalizada
            theta_inf = (f_inf/app.fs)*2*pi;
            theta_sup = (f_sup/app.fs)*2*pi;
            %theta_bw = theta_sup - theta_inf;

            % Establezco el orden del filtro % CORREGIR PARA CASO ORDEN PAR
            N = 221;
            n = 0:N;

            % Calculo filtro prototipo
            ha = (sin(theta_inf*(n-N/2)))./(pi.*(n-N/2)); % Pasa bajas
            hb = ((-1).^(n)).*(sin((pi-theta_sup).*(n-N/2)))./(pi.*(n-N/2)); % Pasa altas
            w = blackman(N+1)';
            hp = ha + hb;
            h = hp .* w;

            % Aplico el filtro a x
            y_FIRR = filter(h, 1, app.x);
            app.y_FIR = y_FIRR;
            %audiowrite("Audios\x_FIR_filtered.wav", y_FIRR, app.fs)

            % Datos en frecuencia
            [HP, FREC] = freqz(hp, 1, 8192, 2*pi); % Filtro prototipo sin aventanear
            [W, ~] = freqz(w, 1, 8192, 2*pi); % Ventana
            [H, Hf] = freqz(h, 1, 8192, 2*pi); % Filtro aventaneado
        end
        
        function results = FIRfilter_radio_graph_select(app, radio, graph, data)
            graphs = ["Impulso", "MagSpect", "Tanto por uno", "Fase"];
            choice = radio.SelectedObject.Text;
            [~ , idx] = ismember(choice, graphs);
            if idx == 1
                app.FIRfilter_timegraph(graph, data)
            elseif idx == 2
                app.FIRfilter_magspectgraph(graph, data)
            elseif idx == 3
                app.FIRfilter_tantoporunograph(graph, data)
            else
                app.FIRfilter_fasegraph(graph, data)
            end
        end
        
        function results = FIRfilter_timegraph(app, graph, data)
            app.clear_graph(graph)
            Fs = app.fs;
            Tc = width(data(1:end-1));
            xaxis = 0:Tc; 
            xlim = [0 Tc];
            xticks = 0:10:Tc;
            xtitle = 'Tiempo (s)';
            yaxis = data;
            ylim = [-0.3 1];
            yticks = -0.3:0.5:1;
            ytitle = 'Amplitud';

            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            graph.XScale = 'linear';
            graph.XTick = xticks;
            graph.XLim = xlim;
            plot(graph, xaxis, yaxis)
            grid on;
        end
        
        function results = FIRfilter_magspectgraph(app, graph, data)
            app.clear_graph(graph)
            xaxis = app.Hf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:3.5;
            xtitle = "MagSpect Filtro FIR";
            yaxis = 20*log10(abs(app.H));
            ylim = [min(yaxis) max(yaxis)];
            yticks = [-15:5:5];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, app.Hf, 20*log10(abs(app.H)));
        end
        
        function results = FIRfilter_tantoporunograph(app, graph, data)
            app.clear_graph(graph)
            xaxis = app.FREC;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:3.5;
            xtitle = "MagSpect Filtro FIR";
            yaxis = abs(app.H);
            ylim = [min(yaxis)  max(yaxis)];
            yticks = [0:0.5:1.0002];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, app.FREC, yaxis);
        end
        
        function results = FIRfilter_fasegraph(app, graph,  data)
            app.clear_graph(graph)
            xaxis = app.Hf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:max(xaxis);
            xtitle = "MagSpect Filtro FIR";
            yaxis = angle(app.H);
            ylim = [min(yaxis)  max(yaxis)];
            yticks = [min(yaxis):2:max(yaxis)];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, xaxis, yaxis);
        end
        
        function results = IIRfilter_radio_graph_select(app, radio, graph, data)
            graphs = ["Impulso", "MagSpect", "Tanto por uno", "Fase"];
            choice = radio.SelectedObject.Text;
            [~ , idx] = ismember(choice, graphs);
            if idx == 1
                app.IIRfilter_timegraph(graph, data)
            elseif idx == 2
                app.IIRfilter_magspectgraph(graph, data)
            elseif idx == 3
                app.IIRfilter_tantoporunograph(graph, data)
            else
                app.IIRfilter_fasegraph(graph, data)
            end
        end
        
        function results = IIRfilter_timegraph(app, graph, data)
            app.clear_graph(graph)
            xaxis = 0:80; 
            xlim = [0 80];
            xticks = 0:10:80;
            xtitle = 'Tiempo (s)';
            yaxis = app.hab';
            ylim = [min(yaxis) max(yaxis)];
            yticks = -0.1:0.1:0.2;
            ytitle = 'Amplitud';

            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            graph.XScale = 'linear';
            graph.XTick = xticks;
            graph.XLim = xlim;
            plot(graph, xaxis, yaxis)
            grid on;
        end
        
        function [impulse, hab, HA, HAf] = IIR_pasabajos(app)
            % Pasabajos tc = tan(0.9425/2), N=8 FUNCIONA
            B = [5.6266e-06 4.5013e-05 1.5755e-04 3.1509e-04 3.9386e-04 3.1509e-04 1.5755e-04 4.5013e-05 5.6266e-06];
            A = [1 -5.3910 13.0240 -18.3415 16.4225 -9.5532 3.5201 -0.7501 0.0707];

            [HA, HAf] = freqz(B, A, 8192, 2*pi);

            impulse_N = 81;
            impulse = [1; zeros(impulse_N-1, 1)];
            hab = filter(B, A, impulse);

            app.y_IIR = filter(B, A, app.x);
            %audiowrite("Audios\x_IIR_filtered.wav", app.y_IIR, app.fs);
        end
        
        function results = IIRfilter_tantoporunograph(app, graph, data)
            app.clear_graph(graph)
            xaxis = app.HAf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:3.5;
            xtitle = "MagSpect Filtro FIR";
            yaxis = abs(app.HA);
            ylim = [min(yaxis)  max(yaxis)];
            yticks = [0:0.5:1.05];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, xaxis, yaxis);
        end
        
        function results = IIRfilter_magspectgraph(app, graph, data)
            app.clear_graph(graph)
            xaxis = app.HAf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:3.5;
            xtitle = "MagSpect Filtro FIR";
            yaxis = 20*log10(abs(app.HA));
            ylim = [min(yaxis) max(yaxis)];
            yticks = [-200:100:0];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, xaxis, yaxis);
        end
        
        function results = IIRfilter_fasegraph(app, graph,  data)
            app.clear_graph(graph)
            xaxis = app.HAf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:max(xaxis);
            xtitle = "MagSpect Filtro FIR";
            yaxis = angle(app.HA);
            ylim = [min(yaxis)  max(yaxis)];
            yticks = [min(yaxis):2:max(yaxis)];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, xaxis, yaxis);
        end
        
        function results = fasegraph(app, graph,  data)
            app.clear_graph(graph)
            xaxis = app.HAf;
            xlim = [0 max(xaxis)];
            xticks = 0:0.5:max(xaxis);
            xtitle = "MagSpect Filtro FIR";
            yaxis = angle(app.HA);
            ylim = [min(yaxis)  max(yaxis)];
            yticks = [min(yaxis):2:max(yaxis)];
            ytitle = "Magnitud";
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            plot(graph, xaxis, yaxis);
        end
        
        function results = get_ECGS(app)
            archivos = dir("ECG_GRUPO\");
            results = {};
            for i=3:height(archivos)
                results{end+1} = archivos(i).name;
            end
        end
        
        function results = analizar_ecg(app)
            [P1, Q1, R1, S1] = get_ComplexQRS(app.y_FIR, app.fs);
            app.P = P1; app.Q = Q1; app.R = R1; app.S = S1;
            app.diagnosis = diagnose_QRS(app.P, app.Q, app.R, app.S, app.fs);
            app.report = qrs_report(app.diagnosis);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Populate input and output drop down settings
            app.ArchivosECGDropDown.Items = app.get_ECGS();
            %Borrar contenido de los graficos
            app.clear_graph(app.UIAxes, app.UIAxes4, app.UIAxes5, app.UIAxes6, app.UIAxes7) % Borro todos los graficos

            % Graficar el primer archivo
            value = app.ArchivosECGDropDown.Value;
            app.x = load("ECG_GRUPO\" + value);
            app.radio_graph_select(app.ECGButtonGroup, app.UIAxes, app.x.x)
        end

        % Callback function
        function FraseButtonGroupSelectionChanged(app, event)
            selectedButton = app.ECGButtonGroup.SelectedObject;
            app.radio_graph_select(app.ECGButtonGroup, app.UIAxes, app.x1)
        end

        % Callback function
        function FIRButtonGroupSelectionChanged(app, event)
            selectedButton = app.FIRButtonGroup.SelectedObject;
            app.FIRfilter_radio_graph_select(app.FIRButtonGroup, app.UIAxes4, app.h)
        end

        % Callback function
        function IIRButtonGroupSelectionChanged(app, event)
            selectedButton = app.IIRButtonGroup.SelectedObject;
            app.IIRfilter_radio_graph_select(app.IIRButtonGroup, app.UIAxes5, app.HA)
        end

        % Callback function
        function FilteredFIRButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilteredFIRButtonGroup.SelectedObject;
            app.radio_graph_select(app.FilteredFIRButtonGroup, app.UIAxes6, app.y_FIR)
        end

        % Callback function
        function FilteredIIRButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilteredIIRButtonGroup.SelectedObject;
            app.radio_graph_select(app.FilteredIIRButtonGroup, app.UIAxes7, app.y_IIR)
        end

        % Value changed function: ArchivosECGDropDown
        function ArchivosECGDropDownValueChanged(app, event)
            value = app.ArchivosECGDropDown.Value;
            app.x = load("ECG_GRUPO\" + value);
            app.radio_graph_select(app.ECGButtonGroup, app.UIAxes, app.x.x)
        end

        % Selection changed function: ECGButtonGroup
        function ECGButtonGroupSelectionChanged(app, event)
            selectedButton = app.ECGButtonGroup.SelectedObject;
            app.radio_graph_select(app.ECGButtonGroup, app.UIAxes, app.x.x)
        end

        % Button pushed function: GenerarfiltradoButton
        function GenerarfiltradoButtonPushed2(app, event)
            f_inf = str2double(app.f_infEditField.Value);
            f_sup = str2double(app.f_supEditField.Value);
            N = str2double(app.NEditField.Value);
            [h1, H1, HF1, FREC1] = bandstop_FIR(f_inf, f_sup, N);
            app.h = h1;
            app.H = H1;
            app.FREC = FREC1;
            app.Hf = HF1;
            app.y_FIR = filter(app.h, 1, app.x.x);

            % [impulse1, hab1, HA1, HAf1] = app.IIR_pasabajos();
            % app.impulse = impulse1;
            % app.hab = hab1;
            % app.HA = HA1;
            % app.HAf = HAf1;

            app.radio_graph_select(app.FilteredFIRButtonGroup, app.UIAxes6, app.y_FIR)
            %app.radio_graph_select(app.FilteredIIRButtonGroup, app.UIAxes7, app.y_IIR)
            app.FIRfilter_radio_graph_select(app.FIRButtonGroup, app.UIAxes4, app.h)
            %app.IIRfilter_radio_graph_select(app.IIRButtonGroup, app.UIAxes5, app.hab)
        end

        % Selection changed function: FIRButtonGroup
        function FIRButtonGroupSelectionChanged2(app, event)
            selectedButton = app.FIRButtonGroup.SelectedObject;
            app.FIRfilter_radio_graph_select(app.FIRButtonGroup, app.UIAxes4, app.h)
        end

        % Selection changed function: FilteredFIRButtonGroup
        function FilteredFIRButtonGroupSelectionChanged2(app, event)
            selectedButton = app.FilteredFIRButtonGroup.SelectedObject;
            app.radio_graph_select(app.FilteredFIRButtonGroup, app.UIAxes6, app.y_FIR)
        end

        % Button pushed function: AnalizarButton
        function AnalizarButtonPushed(app, event)
            app.analizar_ecg();
            app.AnalisisECGTextArea.Value = app.report;

            data = app.y_FIR;
            app.clear_graph(app.UIAxes8)
            Fs = app.fs;
            Tc = width(data(1:end-1))/Fs;
            xaxis = 1:1:2000;
            xlim = [0 2000];
            xticks = 0:200:2000;
            xtitle = 'Tiempo (s)';
            yaxis = data;
            ylim = [-2 2];
            yticks = -2:0.5:2;
            ytitle = 'Amplitud';

            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, app.UIAxes8)
            graph.XScale = 'linear';
            graph.XTick = xticks;
            graph.XLim = xlim;
            grid on;
            plot(app.UIAxes8, xaxis, yaxis)
            hold(app.UIAxes8,'on');

            plot(app.UIAxes8, app.P(:, 2), app.P(:, 1), "ro", 'MarkerFaceColor', 'r');
            plot(app.UIAxes8, app.Q(:, 2), app.Q(:, 1), "go", 'MarkerFaceColor', 'g');
            plot(app.UIAxes8, app.R(:, 2), app.R(:, 1), "bo", 'MarkerFaceColor', 'b');
            plot(app.UIAxes8, app.S(:, 2), app.S(:, 1), "yo", 'MarkerFaceColor', 'y');
            legend(app.UIAxes8, "ECG", "P", "Q", "R", "S");
        end

        % Button pushed function: GrabarExcelButton
        function GrabarExcelButtonPushed(app, event)
            qrs_to_excel(app.P, app.Q, app.R, app.S, app.fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EXP1Tab = uitab(app.TabGroup);
            app.EXP1Tab.Title = 'EXP1';
            app.UIAxes = uiaxes(app.EXP1Tab);
            title(app.UIAxes, 'ECG')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes4 = uiaxes(app.EXP1Tab);
            title(app.UIAxes4, 'FIR')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.EXP1Tab);
            title(app.UIAxes5, 'IIR')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.EXP1Tab);
            title(app.UIAxes6, 'Señal filtrada FIR')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.EXP1Tab);
            title(app.UIAxes7, 'Señal filtrada IIR')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.EXP1Tab);
            title(app.UIAxes8, 'ECG: Analisis')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.ECGButtonGroup = uibuttongroup(app.EXP1Tab);
            app.ECGButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ECGButtonGroupSelectionChanged, true);
            app.ECGButtonGroup.Title = 'ECG';
            app.TiempoButton = uiradiobutton(app.ECGButtonGroup);
            app.TiempoButton.Text = 'Tiempo';
            app.TiempoButton.Value = true;
            app.MagSpectButton = uiradiobutton(app.ECGButtonGroup);
            app.MagSpectButton.Text = 'MagSpect';
            app.SpectrogramButton = uiradiobutton(app.ECGButtonGroup);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.f_infEditFieldLabel = uilabel(app.EXP1Tab);
            app.f_infEditFieldLabel.Text = 'f_inf';
            app.f_infEditField = uieditfield(app.EXP1Tab, 'text');
            app.f_supEditFieldLabel = uilabel(app.EXP1Tab);
            app.f_supEditFieldLabel.Text = 'f_sup';
            app.f_supEditField = uieditfield(app.EXP1Tab, 'text');
            app.FIRButtonGroup = uibuttongroup(app.EXP1Tab);
            app.FIRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FIRButtonGroupSelectionChanged2, true);
            app.FIRButtonGroup.Title = 'FIR';
            app.ImpulsoButton = uiradiobutton(app.FIRButtonGroup);
            app.ImpulsoButton.Text = 'Impulso';
            app.ImpulsoButton.Value = true;
            app.TantoporunoButton = uiradiobutton(app.FIRButtonGroup);
            app.TantoporunoButton.Text = 'Tanto por uno';
            app.MagSpectButton_4 = uiradiobutton(app.FIRButtonGroup);
            app.MagSpectButton_4.Text = 'MagSpect';
            app.FaseButton = uiradiobutton(app.FIRButtonGroup);
            app.FaseButton.Text = 'Fase';
            app.IIRButtonGroup = uibuttongroup(app.EXP1Tab);
            app.IIRButtonGroup.Title = 'IIR';
            app.ImpulsoButton_2 = uiradiobutton(app.IIRButtonGroup);
            app.ImpulsoButton_2.Text = 'Impulso';
            app.ImpulsoButton_2.Value = true;
            app.TantoporunoButton_2 = uiradiobutton(app.IIRButtonGroup);
            app.TantoporunoButton_2.Text = 'Tanto por uno';
            app.MagSpectButton_5 = uiradiobutton(app.IIRButtonGroup);
            app.MagSpectButton_5.Text = 'MagSpect';
            app.FaseButton_2 = uiradiobutton(app.IIRButtonGroup);
            app.FaseButton_2.Text = 'Fase';
            app.ParametrosFIRLabel = uilabel(app.EXP1Tab);
            app.ParametrosFIRLabel.Text = 'Parametros FIR';
            app.FilteredFIRButtonGroup = uibuttongroup(app.EXP1Tab);
            app.FilteredFIRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilteredFIRButtonGroupSelectionChanged2, true);
            app.FilteredFIRButtonGroup.Title = 'Filtered FIR';
            app.MagSpectButton_6 = uiradiobutton(app.FilteredFIRButtonGroup);
            app.MagSpectButton_6.Text = 'MagSpect';
            app.MagSpectButton_6.Value = true;
            app.FaseButton_3 = uiradiobutton(app.FilteredFIRButtonGroup);
            app.FaseButton_3.Text = 'Fase';
            app.SpectrogramButton_4 = uiradiobutton(app.FilteredFIRButtonGroup);
            app.SpectrogramButton_4.Text = 'Spectrogram';
            app.TiempoButton_2 = uiradiobutton(app.FilteredFIRButtonGroup);
            app.TiempoButton_2.Text = 'Tiempo';
            app.GenerarfiltradoButton = uibutton(app.EXP1Tab, 'push');
            app.GenerarfiltradoButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarfiltradoButtonPushed2, true);
            app.GenerarfiltradoButton.Text = 'Generar filtrado';
            app.FilteredIIRButtonGroup = uibuttongroup(app.EXP1Tab);
            app.FilteredIIRButtonGroup.Title = 'Filtered IIR';
            app.MagSpectButton_7 = uiradiobutton(app.FilteredIIRButtonGroup);
            app.MagSpectButton_7.Text = 'MagSpect';
            app.MagSpectButton_7.Value = true;
            app.FaseButton_4 = uiradiobutton(app.FilteredIIRButtonGroup);
            app.FaseButton_4.Text = 'Fase';
            app.SpectrogramButton_5 = uiradiobutton(app.FilteredIIRButtonGroup);
            app.SpectrogramButton_5.Text = 'Spectrogram';
            app.ArchivosECGDropDownLabel = uilabel(app.EXP1Tab);
            app.ArchivosECGDropDownLabel.Text = 'Archivos ECG';
            app.ArchivosECGDropDown = uidropdown(app.EXP1Tab);
            app.ArchivosECGDropDown.ValueChangedFcn = createCallbackFcn(app, @ArchivosECGDropDownValueChanged, true);
            app.NEditFieldLabel = uilabel(app.EXP1Tab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.EXP1Tab, 'text');
            app.AnalisisECGTextAreaLabel = uilabel(app.EXP1Tab);
            app.AnalisisECGTextAreaLabel.Text = 'Analisis ECG';
            app.AnalisisECGTextArea = uitextarea(app.EXP1Tab);
            app.AnalizarButton = uibutton(app.EXP1Tab, 'push');
            app.AnalizarButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButtonPushed, true);
            app.AnalizarButton.Text = 'Analizar';
            app.GrabarExcelButton = uibutton(app.EXP1Tab, 'push');
            app.GrabarExcelButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarExcelButtonPushed, true);
            app.GrabarExcelButton.Text = 'Grabar Excel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDS_TF_EXP2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end